classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        LeftPanel                   matlab.ui.container.Panel
        RSDDenavitHartenburgLabel   matlab.ui.control.Label
        ArmPanel                    matlab.ui.container.Panel
        LinksSliderLabel            matlab.ui.control.Label
        LinksSlider                 matlab.ui.control.Slider
        AdjustinglinksresetstheparametersLabel  matlab.ui.control.Label
        DHTable                     matlab.ui.control.Table
        CalculateMatriciesButton    matlab.ui.control.Button
        CalculationModeButtonGroup  matlab.ui.container.ButtonGroup
        SymbolicButton              matlab.ui.control.ToggleButton
        RealButton                  matlab.ui.control.ToggleButton
        RightPanel                  matlab.ui.container.Panel
        TabGroup                    matlab.ui.container.TabGroup
        MatriciesTab                matlab.ui.container.Tab
        OuputTextAreaLabel          matlab.ui.control.Label
        OuputTextArea               matlab.ui.control.TextArea
        OutputTable                 matlab.ui.control.Table
        WorkingOutTab               matlab.ui.container.Tab
    end

    
    properties (Access = private)
        calcModeSymbolic = true % true = Symbolic, false = real
    end
    
    methods (Access = private)
        
        function mat = rotMatZ(app,theta)
            mat = [ cos(theta) -sin(theta) 0 0; ...
                    sin(theta) cos(theta) 0 0 ; ...
                    0 0 1 0; ...
                    0 0 0 1];
        end
        
        function mat = rotMatY(app,theta)
            mat = [ cos(theta) 0 sin(theta) 0; ...
                    0 1 0 0; ...
                    -sin(theta) 0 cos(theta) 0 ; ...
                    0 0 0 1];
        end
        
        function mat = rotMatX(app,theta)
            mat = [1 0 0 0; ...
                    0 cos(theta) -sin(theta) 0; ...
                    0 sin(theta) cos(theta) 0 ; ...
                    0 0 0 1];
        end
        
        function mat = xyzMat(app,x,y,z)
            mat = [ 1 0 0 x; ...
                    0 1 0 y; ...
                    0 0 1 z; ...
                    0 0 0 1 ];
        end
        
        function mat = processDHTable(app, row)
            
            mat = eye(4);
            
            % Col 1 of DH is the angular displacement between Z axis
            %  about X, hence it's an X-rotation
            if (~isempty(app.DHTable.Data{row,1})) % if it is empty
                inputValue = str2num(app.DHTable.Data{row,1});
                if (~isempty(inputValue) && ~app.calcModeSymbolic )
                    %input is a number
                    %convert to deg
                    mat = mat * rotMatX(app,inputValue*180/pi);
                else
                    %input is symbolic
                    matTheta = str2sym(app.DHTable.Data{row,1});
                    mat = simplify(mat * rotMatX(app,matTheta));
                end
                
            end
            
            % Col 2 of DH is the displacement between Z axis
            %  *along* the X, hence, it's an X-displacement.
            if (~isempty(app.DHTable.Data{row,2})) % if it is empty
                inputValue = str2num(app.DHTable.Data{row,2});
                if (~isempty(inputValue) && ~app.calcModeSymbolic )
                    %input is a number
                    mat = mat * xyzMat(app, 0, 0, inputValue);
                else
                    %input is symbolic
                    matDist = str2sym(app.DHTable.Data{row,2});
                    mat = simplify(mat * xyzMat(app, 0, 0, matDist));
                end
                
            end
            
            
            if (~isempty(app.DHTable.Data{row,4})) % if it is empty
                inputValue = str2num(app.DHTable.Data{row,4});
                if (~isempty(inputValue) && ~app.calcModeSymbolic )
                    %input is a number
                    mat = mat * xyzMat(app, inputValue, 0, 0);
                else
                    %input is symbolic
                    matDist = str2sym(app.DHTable.Data{row,4});
                    mat = simplify(mat * xyzMat(app, matDist, 0, 0));
                end
                
            end
            
            if (~isempty(app.DHTable.Data{row,3})) % if it is empty
                inputValue = str2num(app.DHTable.Data{row,3});
                if (~isempty(inputValue) && ~app.calcModeSymbolic )
                    %input is a number
                    %convert to deg
                    mat = mat * rotMatZ(app,inputValue*180/pi);
                else
                    %input is symbolic
                    matTheta = str2sym(app.DHTable.Data{row,3});
                    mat = simplify(mat * rotMatZ(app,matTheta));
                end
                
            end
           
            
        end
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DHTable.Data = {'' '' '' ''; '' '' '' ''; '' '' '' ''};
            
        end

        % Value changed function: LinksSlider
        function LinksSliderValueChanged(app, event)
            app.LinksSlider.Value = round(app.LinksSlider.Value);
            value = app.LinksSlider.Value;
            
            newData = {'' '' '' ''}; %blank data for each column
            app.DHTable.Data = {};
            for i=1:value
                app.DHTable.Data = [app.DHTable.Data; newData];
            end
            
        end

        % Button pushed function: CalculateMatriciesButton
        function CalculateMatriciesButtonPushed(app, event)
            app.OutputTable.Data={}; % Reset Output Table Data
            syms t1 t2 t3 l1 l2 l3 d1 d2 d3 theta phi;


            % -------- Process Hs -------- %
            
            H01 = processDHTable(app,1);
            H12 = processDHTable(app,2);
            H23 = processDHTable(app,3);
            
            H03 = simplify(H01*H12*H23);
            
            H01Table = array2table(H01);
            H01TableChar = {size(H01,1),size(H01,2)};
            
            H12Table = array2table(H12);
            H12TableChar = {size(H12,1),size(H12,2)};
            
            H23Table = array2table(H23);
            H23TableChar = {size(H23,1),size(H23,2)};
            
            H03Table = array2table(H03);
            H03TableChar = {size(H03,1),size(H03,2)};
            
            for x = 1:size(H01,1)
                for y = 1:size(H01,2)
                    H01TableChar{x,y} = sprintf('%s',H01Table{x,y});
                end
            end
            
            for x = 1:size(H12,1)
                for y = 1:size(H12,2)
                    H12TableChar{x,y} = sprintf('%s',H12Table{x,y});
                end
            end
            
            for x = 1:size(H23,1)
                for y = 1:size(H23,2)
                    H23TableChar{x,y} = sprintf('%s',H23Table{x,y});
                end
            end
            
            for x = 1:size(H03,1)
                for y = 1:size(H03,2)
                    H03TableChar{x,y} = sprintf('%s',H03Table{x,y});
                end
            end
            
            H01Label = {'H01' '' '' ''};
            H12Label = {'H12' '' '' ''};
            H23Label = {'H23' '' '' ''};
            H03Label = {'H03' '' '' ''};


            app.OutputTable.Data = [H01Label; H01TableChar; ...
                                    H12Label; H12TableChar;
                                    H23Label; H23TableChar;
                                    H03Label; H03TableChar];
            addStyle(app.OutputTable, uistyle('BackgroundColor', '#DDD'),'row', [1:5:40]);
            
            %A = rotMatZ(app,theta);
            %app.OutputTable.Data=array2table(A);


            %dataTable = array2table(A);
            
            %app.OuputTextArea.Value = char(app.OutputTable.Data{2,2});
            %app.OuputTextArea.Value = [app.OuputTextArea.Value; "new line"];
            
            %app.OutputTable.Data{2,2} = char(app.OutputTable.Data{2,2});
            
            %app.OutputTable.Data = dataTest{1,1};
            
            %for x = 1:size(A,1)
            %    for y = 1:size(A,2)
            %        app.OutputTable.Data{x,y} = char(dataTable{x,y});
            %    end
            %end


        end

        % Selection changed function: CalculationModeButtonGroup
        function CalculationModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.CalculationModeButtonGroup.SelectedObject;
            app.calcModeSymbolic = selectedButton == app.SymbolicButton;
            app.OuputTextArea.Value = sprintf('%d',app.calcModeSymbolic);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LeftPanel = uipanel(app.UIFigure);
            app.RSDDenavitHartenburgLabel = uilabel(app.LeftPanel);
            app.RSDDenavitHartenburgLabel.Text = 'RSD Denavit-Hartenburg';
            app.ArmPanel = uipanel(app.LeftPanel);
            app.ArmPanel.Title = 'Arm Parameters';
            app.LinksSliderLabel = uilabel(app.ArmPanel);
            app.LinksSliderLabel.Text = 'Links';
            app.LinksSlider = uislider(app.ArmPanel);
            app.LinksSlider.Limits = [1 3];
            app.LinksSlider.MajorTicks = [1 2 3];
            app.LinksSlider.ValueChangedFcn = createCallbackFcn(app, @LinksSliderValueChanged, true);
            app.LinksSlider.MinorTicks = [];
            app.LinksSlider.Value = 3;
            app.AdjustinglinksresetstheparametersLabel = uilabel(app.ArmPanel);
            app.AdjustinglinksresetstheparametersLabel.Text = 'Adjusting links resets the parameters!';
            app.DHTable = uitable(app.LeftPanel);
            app.DHTable.ColumnName = {'alpha'; 'a'; 'theta'; 'd'};
            app.DHTable.RowName = {};
            app.DHTable.ColumnEditable = true;
            app.CalculateMatriciesButton = uibutton(app.LeftPanel, 'push');
            app.CalculateMatriciesButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateMatriciesButtonPushed, true);
            app.CalculateMatriciesButton.Text = 'Calculate Matricies';
            app.CalculationModeButtonGroup = uibuttongroup(app.LeftPanel);
            app.CalculationModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CalculationModeButtonGroupSelectionChanged, true);
            app.CalculationModeButtonGroup.Title = 'Calculation Mode';
            app.SymbolicButton = uitogglebutton(app.CalculationModeButtonGroup);
            app.SymbolicButton.Text = 'Symbolic';
            app.SymbolicButton.Value = true;
            app.RealButton = uitogglebutton(app.CalculationModeButtonGroup);
            app.RealButton.Text = 'Real';
            app.RightPanel = uipanel(app.UIFigure);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.MatriciesTab = uitab(app.TabGroup);
            app.MatriciesTab.Title = 'Matricies';
            app.OuputTextAreaLabel = uilabel(app.MatriciesTab);
            app.OuputTextAreaLabel.Text = 'Ouput';
            app.OuputTextArea = uitextarea(app.MatriciesTab);
            app.OutputTable = uitable(app.MatriciesTab);
            app.OutputTable.ColumnName = {''};
            app.OutputTable.RowName = {};
            app.WorkingOutTab = uitab(app.TabGroup);
            app.WorkingOutTab.Title = 'Working Out';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end